classdef TestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        LoadDataButton          matlab.ui.control.Button
        ShowaverageplotButton   matlab.ui.control.Button
        ColorSchemeButtonGroup  matlab.ui.container.ButtonGroup
        IntensityDerivedButton  matlab.ui.control.RadioButton
        RGBButton               matlab.ui.control.RadioButton
        RightPanel              matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        averageKinPlot          matlab.ui.control.UIAxes
        pixelPlot               matlab.ui.control.UIAxes
        kinPlot                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function readData(app)
            global Data LogData Wavelength
            FolderPath = uigetdir;

            FolderContent = dir(FolderPath);
            
            Data = containers.Map();
            LogData = containers.Map();
            TimeObtained = false;
            
            for FileIndex = 1:1:size(FolderContent,1)
                if endsWith(FolderContent(FileIndex).name, 'Y.tdms') == 1
                    Wavelength = extractBefore(FolderContent(FileIndex).name,'nm');
                    %disp(ismember(Wavelength, Data.keys));
                    if ~ismember(Wavelength, Data.keys)
                        Data(Wavelength) = table();
                        LogData(Wavelength) = table();
                    end
                    %X = extractBetween(FolderContent(FileIndex).name, 'nm','X');
                    %Y = extractBetween(FolderContent(FileIndex).name, 'X','Y');
                    %disp(Wavelength + " " + X + " " + Y);
                    TDMScontent = TDMS_readTDMSFile([FolderPath, '\', FolderContent(FileIndex).name]);
                    CH0GroupIndex = find(strcmp(TDMScontent.groupNames,'CH0'));
                    AbsChanIndex = strcmp(TDMScontent.chanNames{1,CH0GroupIndex},'final A-B');
                    AbsDataIndex = TDMScontent.chanIndices{1,CH0GroupIndex}(AbsChanIndex);
                    
                    Abs = transpose(TDMScontent.data{1,AbsDataIndex});
                    if ~TimeObtained
                        TimeGroupIndex = strcmp(TDMScontent.groupNames,'Time');
                        TimeDataIndex = TDMScontent.chanIndices{1,TimeGroupIndex};
                        Time = transpose(TDMScontent.data{1,TimeDataIndex});
                        Time = array2table(Time);
            
                        Data("Time") = Time;
                        TimeObtained = true;
                    end
                    Data(Wavelength) = addvars(Data(Wavelength),Abs, 'NewVariableNames', extractBetween(FolderContent(FileIndex).name, 'nm','.tdms'));
                    [LogTimeArray, LogAbsArray] = lin2log_TAS(table2array(Data("Time")), Abs);
                    LogData("Time") = LogTimeArray;
                    LogData(Wavelength) = addvars(LogData(Wavelength), LogAbsArray, 'NewVariableNames', extractBetween(FolderContent(FileIndex).name, 'nm','.tdms'));
                end
            end
        end
        function plotData(app, src, position)
            global Xpos Ypos LogData Wavelength pixelArray
            if nargin == 1
                %% Plot the pixels
                app.pixelPlot.XScale='linear';
                pixelArray = {};
                for pos = 1:size(Xpos,2)
                    hold(app.pixelPlot, "on");
                    scatter(app.pixelPlot, Xpos(1,pos), Ypos(1,pos));
                    this = drawpoint(app.pixelPlot, "Position", [Xpos(pos) Ypos(pos)]);
                    pixelArray{pos} = this;
                    plotName = "";
                    plotName = plotName + Xpos(pos) + "X" + Ypos(pos) + "Y";
                    addlistener(this, "ROIClicked", @(src,~)plotData(app,src,plotName));
                end
                hold(app.pixelPlot,"off");
                %% Plot the average kinetic data
                %% Calling plotKinetics with nargin == 1 plots average data
                plotKinetics(app);
            else
                %disp(src)
                src.Position = [str2double(extractBefore(position,'X')) str2double(extractBetween(position, 'X', 'Y'))];
                plotKinetics(app, position);
            end
        end
        
        function plotKinetics(app, position, src)
            global LogData Wavelength kinPlot t_0_Point t_0_line plotLine t_50_line t_0_line2
            %% get positive time only
            time_array = LogData("Time");
            positive_time = time_array(time_array > 0);
            app.kinPlot.XScale='log';
            
            
            if nargin == 1
                % get average for all rows
                average_data = mean(table2array(LogData(Wavelength)), 2);
                
                % get data in positive time only
                positive_time_average_data = average_data(time_array > 0);
                kinPlot = plot(app.averageKinPlot, positive_time, positive_time_average_data);
                
                % get highest point and set as nitial t_0
                max_index = positive_time_average_data == max(positive_time_average_data);
                initial_t_0 = positive_time(max_index);
                hold(app.averageKinPlot,"on");
                hold(app.kinPlot,"on");
                t_0_Point = drawpoint(app.averageKinPlot, "Position", [initial_t_0 0]);
                addlistener(t_0_Point, "MovingROI", @(src,~)plotKinetics(app,0,src));
                t_0_line = xline(app.averageKinPlot, initial_t_0);
                t_0_line2 = xline(app.kinPlot, initial_t_0);
                hold(app.averageKinPlot, "off");
                hold(app.kinPlot, "off");
                
                setColors(app);
                
                
            elseif nargin == 2
                %% Plot individual PixelData
                % Hide line, point and graph
                %t_0_Point.Visible='off';
                %kinPlot.Visible='off';
                delete(t_50_line);
                delete(plotLine);
                
                table_index = LogData(Wavelength).Properties.VariableNames==position;
                %disp(table_index);
                table_data = LogData(Wavelength);
                plot_data = table_data(:,table_index);
                
                app.kinPlot.XScale='log';
                %% get data in positive time only
                time_array = LogData("Time");
                plot_data = table2array(plot_data);
                positive_time = time_array(time_array >= t_0_line.Value);
                positive_time_data = plot_data(time_array >= t_0_line.Value);
                
                % Get highest point on plot
                max_index = positive_time == t_0_line.Value;
                max_value = positive_time_data(max_index);
                [~, half_index] = min(abs(positive_time_data - max_value/2));

                t_50 = positive_time(half_index);
               
                hold(app.kinPlot, "on");
                plotLine = plot(app.kinPlot, LogData("Time"), plot_data);
                t_50_line = xline(app.kinPlot, t_50);
                hold(app.kinPlot, "off");
            elseif nargin == 3
                %% Main T_0 line moves
                delete(t_0_line);
                delete(t_0_line2);
                
                % get new t_0
                [~, new_index] = min(abs(positive_time - t_0_Point.Position(1)));
                if t_0_Point.Position(1) > max(positive_time)
                    t_0_Point.Position = [max(positive_time) 0];
                elseif t_0_Point.Position(1) < min(positive_time)
                    t_0_Point.Position = [min(positive_time) 0];
                else
                    t_0_Point.Position = [t_0_Point.Position(1) 0];
                end
                
                hold(app.averageKinPlot,"on");
                hold(app.kinPlot, "on");
                t_0_line = xline(app.averageKinPlot, positive_time(new_index));
                t_0_line2 = xline(app.kinPlot, positive_time(new_index));
                hold(app.averageKinPlot, "off");
                hold(app.kinPlot, "off");
                setColors(app);
            end
        end
        
        function setColors(app)
            global LogData Wavelength pixelArray t_0_line            
            %% After getting T_0, get the T_50% of all other values and
            %% Hold them in array
            
            data = LogData(Wavelength);
            time_array = LogData("Time");
            positive_time = time_array(time_array >=t_0_line.Value);
            positive_data = data(time_array >= t_0_line.Value, :);
            t_0_index = positive_time == t_0_line.Value;
            t_0_values = table2array(positive_data(t_0_index, :));
            t_50_values = t_0_values/2;
            [~, half_indices] = min(abs(table2array(positive_data) - t_50_values));
            t_50_times = positive_time(half_indices);
            
            %%% get the time value at t50%
            %% convert to hsv make higher intensity 
            max_t50_time = max(t_50_times);
            min_t50_time = min(t_50_times);
            colorMap = turbo();
            selectedButton = app.ColorSchemeButtonGroup.SelectedObject;
            for value = 1:length(t_0_values)
                color = round(interp1(log10([min_t50_time, max_t50_time]), [30,length(colorMap)], log10(t_50_times(value))));
                colorHSV = rgb2hsv(colorMap(color,:));
                if selectedButton == app.IntensityDerivedButton
                    new_V = interp1([0 max(abs(t_0_values))], [0,1], abs(t_0_values(value)));
                    colorHSV(3) = new_V;
                end

                pixelArray{value}.Color = hsv2rgb(colorHSV); 
            end 
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStart(app)
            % Determine where your m-file's folder is.
            folder = fileparts(which(mfilename));
            % Add that folder plus all subfolders to the path.
            addpath(genpath(folder));
            app.kinPlot.XScale='log';
            app.averageKinPlot.XScale='log';
            disableDefaultInteractivity(app.kinPlot);
            disableDefaultInteractivity(app.averageKinPlot);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {620, 620};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadDataButton
        function loadDataButtonPushed(app, event)
            global Data Xpos Ypos Wavelength
            readData(app)

            Xpos = str2double(extractBefore(Data(Wavelength).Properties.VariableNames, "X"));
            Ypos = str2double(extractBetween(Data(Wavelength).Properties.VariableNames, "X", "Y"));

            plotData(app);
        end

        % Button pushed function: ShowaverageplotButton
        function plotAverage(app, event)
            global t_0_Point kinPlot t_50_line plotLine
            t_0_Point.Visible='on';
            kinPlot.Visible='on';
            delete(t_50_line);
            delete(plotLine);
        end

        % Selection changed function: ColorSchemeButtonGroup
        function changeColor(app, event)
            setColors(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.LoadDataButton = uibutton(app.GridLayout3, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ShowaverageplotButton = uibutton(app.GridLayout3, 'push');
            app.ShowaverageplotButton.ButtonPushedFcn = createCallbackFcn(app, @plotAverage, true);
            app.ShowaverageplotButton.Text = 'Show average plot';
            app.ColorSchemeButtonGroup = uibuttongroup(app.GridLayout3);
            app.ColorSchemeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changeColor, true);
            app.ColorSchemeButtonGroup.Title = 'Color Scheme';
            app.IntensityDerivedButton = uiradiobutton(app.ColorSchemeButtonGroup);
            app.IntensityDerivedButton.Text = 'Intensity Derived';
            app.IntensityDerivedButton.Value = true;
            app.RGBButton = uiradiobutton(app.ColorSchemeButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.averageKinPlot = uiaxes(app.GridLayout4);
            title(app.averageKinPlot, 'Title')
            xlabel(app.averageKinPlot, 'X')
            ylabel(app.averageKinPlot, 'Y')
            zlabel(app.averageKinPlot, 'Z')
            app.pixelPlot = uiaxes(app.GridLayout4);
            title(app.pixelPlot, 'Title')
            xlabel(app.pixelPlot, 'X')
            ylabel(app.pixelPlot, 'Y')
            zlabel(app.pixelPlot, 'Z')
            app.kinPlot = uiaxes(app.GridLayout2);
            title(app.kinPlot, 'KinPlot')
            xlabel(app.kinPlot, 'X')
            ylabel(app.kinPlot, 'Y')
            zlabel(app.kinPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end